classdef Mortgage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MortgageCalculatorUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        CheckBox                    matlab.ui.control.CheckBox
        CCM                         matlab.ui.control.Spinner
        CCMSpinnerLabel             matlab.ui.control.Label
        P                           matlab.ui.control.Spinner
        PWSpinnerLabel              matlab.ui.control.Label
        Uo                          matlab.ui.control.Spinner
        UoVSpinnerLabel             matlab.ui.control.Label
        Ui                          matlab.ui.control.Spinner
        UiVLabel                    matlab.ui.control.Label
        Um                          matlab.ui.control.Spinner
        UmVSpinnerLabel             matlab.ui.control.Label
        f                           matlab.ui.control.Spinner
        fKHzSpinnerLabel            matlab.ui.control.Label
        dU                          matlab.ui.control.Spinner
        VSpinnerLabel               matlab.ui.control.Label
        L1KHzQ2545874796Label       matlab.ui.control.Label
        hhbLabel                    matlab.ui.control.Label
        BuckBoostLabel              matlab.ui.control.Label
        OK                          matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        C2                          matlab.ui.control.Label
        R2                          matlab.ui.control.Label
        L2                          matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        aaa                         matlab.ui.control.Label
        y                           matlab.ui.control.Label
        wc                          matlab.ui.control.Label
        bb                          matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        aa                          matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        bbb                         matlab.ui.control.Label
        BoostLabel                  matlab.ui.control.Label
        BuckLabel                   matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MortgageCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {256, 256};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {462, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function MonthlyPaymentButtonPushed(app, event)
                       
            % Calculate the monthly payment
            amount = app.Ui.Value;
            rate = app.Uo.Value/12/100;
            nper = 12*app.Um.Value;
            payment = (amount*rate)/(1-(1+rate)^-nper);
            app.MonthlyPaymentEditField.Value = payment;
            
            % pre allocating and initializing variables
            interest = zeros(1,nper);
            principal = zeros(1,nper);
            balance = zeros (1,nper);
            
            balance(1) = amount;
            
            % Calculate the principal and interest over time
            for i = 1:nper
                interest(i)  = balance(i)*rate;
                principal(i) = payment - interest(i);
                balance(i+1) = balance(i) - principal(i);
            end
            
            % Plot the principal and interest
            plot(app.PrincipalInterestUIAxes,(1:nper)',principal, ...
                (1:nper)',interest);
            legend(app.PrincipalInterestUIAxes,{'Principal','Interest'},'Location','Best')
            xlim(app.PrincipalInterestUIAxes,[0 nper]); 
            
        end

        % Button pushed function: OK
        function OKButtonPushed(app, event)
            clc;
            close all;
            %% 求解Buck参数
            Ui = app.Ui.Value;
            Uo = app.Uo.Value;
            dU = app.dU.Value;
            f = app.f.Value*1000;
            P = app.P.Value;
            Um = app.Um.Value;
            R = Uo*Uo/P;
            if Ui>=Uo
                fprintf('Buck电路\n');
                D = Uo/Ui;
                L = app.CCM.Value*Uo*(1-D)/(2*Uo/R)*(1/f);
                C = Uo*(1-D)/(8*L*dU)*(1/f)*(1/f);
                fprintf('R=%.3f欧; L=%.9fH; C=%.9fF\n',R,L,C);
                app.R2.Text = "R(欧): "+R;
                app.L2.Text = "L(H): "+L;
                app.C2.Text = "C(F): "+C;
                
                num = app.Uo.Value/(Um*D);        %分子多项式的系数
                den = [L*C L/R 1]; %分母多项式的系数
                G0 = tf(num, den)
                [~,pm,~,wcp]=margin(G0);
                fprintf('原系统截止频率为：%.0f rad/s; ',wcp);
                fprintf('相角裕度为：%.2f 度\n',pm);    
                if app.CheckBox.Value
                    figure(1);
                    subplot(1,2,1);
                    %w = logspace(2, 5);
                    bode(num,den); grid on;
                    % 设置曲线的线宽为加粗
                    h = findobj(gcf, 'Type', 'line');
                    set(h, 'LineWidth', 1.5);
                    title('原系统的Bode图'); % 设置原始的图形标题
                end
                
                %% 校正
                gamma = 45;
                zhbc = 5;
                dPm = gamma + zhbc;
                w = logspace(3, 4, 100000);             % 设定w数量，使得解更精确     
                [mag,phase,w]=bode(G0,w);
                wcg = spline(squeeze(phase),w,dPm-180); %求解相角裕度对应的频率
                fprintf('期望相角裕度对应频率为：%.0f rad/s\n',wcg);
                magdb = 20*log10(mag);                  %化为对数坐标
                
                %% PI
                mag = spline(w,magdb,wcg); %求解相角裕度对应的频率
                fprintf('相应的幅值为：%.2f\n', mag);
    
                Kp = 10^(-mag/20);
                T = 10/wcg;
                Ki = Kp/T;
                fprintf('相应的Kp、Ki为：%f,%f\n', Kp, Ki);
                fprintf('校正器传递函数为：\n');
                app.aaa.Text = Kp+"s+"+Ki;
                app.Label.Text = "------------------------------------";
                app.bbb.Text = "s";
                app.aa.Text = "";
                app.bb.Text = "";
                app.Label_2.Text = "";
                Gc = tf([Kp, Ki],[1 0])
                Gc0 = Gc*G0;
                
                %% 校正后伯德图
                [~,pm,~,wcp]=margin(Gc0);
                fprintf('校正后截止频率为：%.0f rad/s; ',wcp);
                fprintf('相角裕度为：%.2f 度\n',pm);
                app.wc.Text = "校正后Wc: "+wcp;
                app.y.Text = "相角裕度: "+pm;
                if app.CheckBox.Value
                    subplot(1,2,2);
                    set(gcf, 'Name', '校正后系统Bode图');
                    %w = logspace(1, 8);
                    bode(Gc0);grid on;
                    % 设置曲线的线宽为加粗
                    h = findobj(gcf, 'Type', 'line');
                    set(h, 'LineWidth', 1.5);
                    title('校正后的Bode图'); % 设置原始的图形标题
                end
            else
                fprintf('Boost电路CMM模式，L取1.2倍\n');
                D = 1-Ui/Uo;
                L = app.CCM.Value*R/2*(1-D)^2*D*(1/f);
                C = Uo*(1-D)/(8*L*dU)*(1/f)*(1/f);
                fprintf('R=%.3f欧; L=%.9fH; C=%.9fF\n',R,L,C);
                app.R2.Text = "R(欧): "+R;
                app.L2.Text = "L(H): "+L;
                app.C2.Text = "C(F): "+C;
                d = 1-D;
                
                num = [-Uo*L/(d*R*Um) Uo*d/Um];   %分子多项式的系数
                den = [L*C L/R d*d];              %分母多项式的系数
                
                
                %% 绘制原始传递函数Bode图
                G0 = tf(num, den)
                [~,pm,~,wcp]=margin(G0);
                    fprintf('原系统截止频率为：%f\n',wcp);
                    fprintf('原系统相角裕度为：%f\n',pm);
                    if app.CheckBox.Value
                        figure(1);
                        subplot(1,2,1);
                        %w = logspace(2, 5);
                        bode(num,den); grid on;
                        % 设置曲线的线宽为加粗
                        h = findobj(gcf, 'Type', 'line');
                        set(h, 'LineWidth', 1.5);
                        title('原系统伯德图'); % 设置原始的图形标题
                    end
                
                
                %% PID控制器
                fc = f/10;
                w0 = d/sqrt(L*C);
                % f0 = W0/(2*pi)
                % fz1 = f0/2;
                % Wz1 = 2*pi*fz1;
                wz1 = w0/2;
                wz2 = w0/2;
                fp1 = 2.5*fc;
                wp1 = fp1*2*pi;
                Gc = tf([1/(wz1*wz2) 1/wz1+1/wz2 1],[1/wp1 1 0]);
                
                %% 求解K
                w = logspace(-1, 6, 100000);             % 设定w数量，使得解更精确 
                [mag,phase,w]=bode(G0*Gc,w);
               % magdb = 20*log10(mag);                  %化为对数坐标
                mag = spline(w,mag,fc/(2*pi)); %求解相角裕度对应的频率
                fprintf('相应的幅值为：%.2f\n', mag);
                
                K = 1/mag;
                % K = 10^(mag/20);
                fprintf('PID校正器K=%f; wz1=%f; wz2=%f; wp1=%f\n',K,wz1,wz2,wp1);
                fprintf('校正器传递函数为：\n');
                Gc = tf([K/(wz1*wz2) K/wz1+K/wz2 K],[1/wp1 1 0])
                app.aaa.Text = "";
                app.Label.Text = "";
                app.bbb.Text = "";
                app.aa.Text = K/(wz1*wz2)+"s^2+"+(K/wz1+K/wz2)+"s+"+K;
                app.bb.Text = 1/wp1+"s^2+s";
                app.Label_2.Text="---------------------------------------------------------";
                [~,pm,~,wcp]=margin(G0*Gc);
                    fprintf('校正后系统截止频率为：%f\n',wcp);
                    fprintf('校正后系统相角裕度为：%f\n',pm);
                    app.wc.Text = "校正后Wc: " + wcp;
                    app.y.Text = "相角裕度: " + pm;
                %     figure(2);
                    if app.CheckBox.Value
                        subplot(1,2,2);
                        %w = logspace(0, 5);
                        bode(G0*Gc); grid on;
                        % 设置曲线的线宽为加粗
                        h = findobj(gcf, 'Type', 'line');
                        set(h, 'LineWidth', 1.5);
                        title('校正后系统伯德图'); % 设置原始的图形标题
                    end
            end
            
               
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MortgageCalculatorUIFigure = uifigure('Visible', 'off');
            app.MortgageCalculatorUIFigure.Name = 'Mortgage Calculator';
            app.MortgageCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MortgageCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OK = uibutton(app.LeftPanel, 'push');
            app.OK.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OK.Text = 'OK';
            app.BuckBoostLabel = uilabel(app.LeftPanel);
            app.BuckBoostLabel.Text = 'Buck/Boost电路频域分析校正';
            app.hhbLabel = uilabel(app.LeftPanel);
            app.hhbLabel.Text = 'hhb';
            app.L1KHzQ2545874796Label = uilabel(app.LeftPanel);
            app.L1KHzQ2545874796Label.Text = {'注：电感倍数为临界L的倍数，默认为1，即临界状态。'; '       开关频率单位已经为KHz！所有微调器均可直接输入'; '       有问题联系Q:2545874796'};
            app.VSpinnerLabel = uilabel(app.LeftPanel);
            app.VSpinnerLabel.Text = '纹波电压(V):';
            app.dU = uispinner(app.LeftPanel);
            app.dU.Step = 0.1;
            app.dU.Limits = [0 Inf];
            app.dU.Value = 0.1;
            app.fKHzSpinnerLabel = uilabel(app.LeftPanel);
            app.fKHzSpinnerLabel.Text = '开关频率f(KHz):';
            app.f = uispinner(app.LeftPanel);
            app.f.Limits = [0 Inf];
            app.f.Value = 10;
            app.UmVSpinnerLabel = uilabel(app.LeftPanel);
            app.UmVSpinnerLabel.Text = '载波最大电压Um(V):';
            app.Um = uispinner(app.LeftPanel);
            app.Um.Step = 0.1;
            app.Um.Limits = [0 Inf];
            app.Um.Value = 3.3;
            app.UiVLabel = uilabel(app.LeftPanel);
            app.UiVLabel.Text = '输入电压Ui(V):';
            app.Ui = uispinner(app.LeftPanel);
            app.Ui.Limits = [0 Inf];
            app.Ui.Value = 10;
            app.UoVSpinnerLabel = uilabel(app.LeftPanel);
            app.UoVSpinnerLabel.Text = '输出电压Uo(V):';
            app.Uo = uispinner(app.LeftPanel);
            app.Uo.Limits = [0 Inf];
            app.Uo.Value = 5;
            app.PWSpinnerLabel = uilabel(app.LeftPanel);
            app.PWSpinnerLabel.Text = '功率P(W):';
            app.P = uispinner(app.LeftPanel);
            app.P.Limits = [0 Inf];
            app.P.Value = 5;
            app.CCMSpinnerLabel = uilabel(app.LeftPanel);
            app.CCMSpinnerLabel.Text = 'CCM模式电感倍数：';
            app.CCM = uispinner(app.LeftPanel);
            app.CCM.Step = 0.1;
            app.CCM.Limits = [1 Inf];
            app.CCM.Value = 1;
            app.CheckBox = uicheckbox(app.LeftPanel);
            app.CheckBox.Text = '是否绘制伯德图';
            app.RightPanel = uipanel(app.GridLayout);
            app.BuckLabel = uilabel(app.RightPanel);
            app.BuckLabel.Text = 'Buck控制器:';
            app.BoostLabel = uilabel(app.RightPanel);
            app.BoostLabel.Text = 'Boost控制器:';
            app.bbb = uilabel(app.RightPanel);
            app.bbb.Text = '';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '';
            app.aa = uilabel(app.RightPanel);
            app.aa.Text = '';
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '';
            app.bb = uilabel(app.RightPanel);
            app.bb.Text = '';
            app.wc = uilabel(app.RightPanel);
            app.wc.Text = '校正后Wc';
            app.y = uilabel(app.RightPanel);
            app.y.Text = '相角裕度';
            app.aaa = uilabel(app.RightPanel);
            app.aaa.Text = '';
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Text = '输出值';
            app.L2 = uilabel(app.RightPanel);
            app.L2.Text = 'L(H): ';
            app.R2 = uilabel(app.RightPanel);
            app.R2.Text = 'R(欧):';
            app.C2 = uilabel(app.RightPanel);
            app.C2.Text = 'C(F):';
            % Show the figure after all components are created
            app.MortgageCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mortgage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MortgageCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MortgageCalculatorUIFigure)
        end
    end
end